classdef BotSensei < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel_3                     matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        InstructionsEditField       matlab.ui.control.EditField
        InstructionsEditFieldLabel  matlab.ui.control.Label
        GridLayout11                matlab.ui.container.GridLayout
        Lamp                        matlab.ui.control.Lamp
        GridLayout10                matlab.ui.container.GridLayout
        Panel_4                     matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        EditField_2                 matlab.ui.control.EditField
        SelectModeDropDown          matlab.ui.control.DropDown
        SelectModeDropDownLabel     matlab.ui.control.Label
        Panel_5                     matlab.ui.container.Panel
        PlayButton                  matlab.ui.control.Button
        SelectLevelDropDown         matlab.ui.control.DropDown
        SelectLevelDropDownLabel    matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        EditField                   matlab.ui.control.EditField
        GridLayout4                 matlab.ui.container.GridLayout
        Panel_2                     matlab.ui.container.Panel
        GridLayout9                 matlab.ui.container.GridLayout
        DoneButton                  matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
        GridLayout6                 matlab.ui.container.GridLayout
        ResetButton                 matlab.ui.control.Button
        RobotYLabel                 matlab.ui.control.Label
        RobotXLabel                 matlab.ui.control.Label
        GridLayout5                 matlab.ui.container.GridLayout
        Image2                      matlab.ui.control.Image
        RIGHTButton                 matlab.ui.control.Button
        DOWNButton                  matlab.ui.control.Button
        LEFTButton                  matlab.ui.control.Button
        UPButton                    matlab.ui.control.Button
    end

% BotSensei est une application destinée à initier des enfants à la programmation
% Le but est de déplacer un robot dans une grille munie d'obstacles à
% l'aide d'instructions. La grille est visible sur l'interface, mais le
% robot se déplace également de manière physique à l'aide d'une structure de support 
    
    properties (Access = private)
        s                               % str, nom du port série pour la liaison RS232
        
        CaseColor = [1 1 1]             % Couleur des cases de la grille, ici blanc
        ObstColor = [0 0 0]             % Couleur des obstacles, ici noir
        CaseColorOld = [0.8 0 1]        % Couleur des carrés indiquant le chemin pris
                                        % par le robot
        FinalCaseColor = [0 1 0.1]      % Couleur de la case d'arrivée
        RobotColor = [1 0 0]            % Couleur du robot, ici rouge
        
        CaseSize = 10                   % Nombre de pixels pour le côté d'une case
        CaseNombre = 10                 % Nombre de lignes, ici égal au nombre 
                                        % de colonnes
        
        RobotX                          % uint8, position actuelle en X et en Y du robot
        RobotY 
        RobotXIni                       % uint8, position initiale en X et en Y du robot 
        RobotYIni
        CaseFinaleX                     % uint8, position en X et en Y de la case d'arrivée
        CaseFinaleY
        
        obstacles                       % uint8xuint8, matrice représentant la grille affichée, 
                                        % composée de 1 (obstacles) et de 0 (cases vides)
        
        instructions = []               % str, initialisation de la ligne de commande qui sera
                                        % envoyée au programme embarqué
                                                                      
        cheminLePlusCourt               % uint8, longueur du chemin le plus court en mode "Shortest Path"
        near                            % uint8xuint8, matrice d'adjacence  
        
        lengthPath                      % uint8, longueur du chemin pris par l'utilisateur
        Message                         % str, message d'échec/réussite à afficher
             
        commandeX                       % str, position initiale selon X du robot
        commandeY                       % str, position initiale selon Y du robot
        SignalNucleo                    % str, signal envoyé par le programme embarqué
        
        checkSandbox                    % choix du mode "Sandbox"
        checkMaze                       % choix du mode "Maze"
        checkMazeDone                   % la grille "Maze" choisie a été effectuée
        checkPath                       % choix du mode "Shortest Path"
        checkPathDone                   % la grille "Shortest Path" choisie a été effectuée
        checkPathTooSoon                % le bouton "Done" a été activé avant d'avoir fini le niveau
            
    end

    methods (Access = private)
        
        function afficherGrille(app)
            % Fonction servant à afficher la grille de jeu : affiche une
            % case blanche pour les cases vides, noire pour les obstacles,
            % verte pour la case d'arrivée
                
            for i = 1:app.CaseNombre
                for j = 1:app.CaseNombre
                    
                    switch app.obstacles(i,j)
                        case 0 % case vide dans la matrice "obstacles"
                            rectangle(app.UIAxes, 'Position', [app.CaseSize*i, app.CaseSize*j, app.CaseSize, app.CaseSize],...
                                'FaceColor', app.CaseColor)
                        case 1 % obstacle dans la matrice "obstacles"
                            rectangle(app.UIAxes, 'Position', [app.CaseSize*i, app.CaseSize*j, app.CaseSize, app.CaseSize],...
                                'FaceColor', app.ObstColor)
                        case 2 % case d'arrivée dans la matrice "obstacles"
                            rectangle(app.UIAxes, 'Position', [app.CaseSize*i, app.CaseSize*j, app.CaseSize, app.CaseSize],...
                                'FaceColor', app.FinalCaseColor)
                    end
                    
                end
            end
        
        end
        
        function deplacerRobot(app, xx, yy)
        % Fonction servant à déplacer le robot dans la grille de xx dans la 
        % direction X et de yy dans la direction Y
        % En mode de jeu "Maze", sert à afficher une image de réussite
        % En mode de jeu "Shortest Path", sert à calculer le chemin le plus
        % court
        
            % On vide la case où se trouvait le robot précédemment en la
            % rendant blanche
            rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX, app.CaseSize*app.RobotY, app.CaseSize, app.CaseSize],...
                'FaceColor',app.CaseColor)
                       
            % On ajoute un carré dans cette même case pour indiquer
            % l'ancienne position du robot et garder le chemin sur la grille            
            rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX+app.CaseSize/3, app.CaseSize*app.RobotY+app.CaseSize/3, ...
                                                   app.CaseSize/3, app.CaseSize/3], ...
                                                   'FaceColor',app.CaseColorOld)
                        
            % Changement de position du robot
            app.RobotX = app.RobotX + xx;
            app.RobotY = app.RobotY + yy;
            
            % On fait apparaître le robot sur la nouvelle case en dessinant
            % un cercle
            rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX, app.CaseSize*app.RobotY, app.CaseSize, app.CaseSize], 'FaceColor',app.RobotColor,'Curvature',[1 1])                   
        
            % Si le robot se déplace sur la case d'arrivée
            if app.obstacles(app.RobotX,app.RobotY) == 2
                
                % en mode "Maze", affiche une image de réussite
                if app.checkMaze
                    
                    app.Image.ImageSource = 'Connie.jpg';
                    app.Image.Visible = 'on';
                    app.Lamp.Color = 'green'; 
                    app.EditField_2.Value = 'SUCCESS!!';
                    
                    app.checkMazeDone = 1;
                    
                % en mode "Shortest Path", on calcule la longueur du chemin le
                % plus court
                elseif app.checkPath
                
                    PathFinding(app);
                    app.checkPathDone = 1;
                    
                end
                    
            end
            
        end
        
        function PathFinding(app)
        % Fonction servant à calculer la longueur du chemin le plus court, pour le mode 
        % de jeu "Shortest Path"
        
            % On définit une matrice d'adjacence
            app.near = zeros(100);
            
            % On ne prend pas en compte les obstacles dans cette matrice
            % d'adjacence, puisque le robot ne pourra jamais se trouver sur
            % une case comportant un obstacle
            app.near(app.obstacles > 1) = 0;
            
            for i=(1:10)
                for j=(1:10)
                    
                    % A nouveau, on ne prend pas en compte les obstacles 
                    if (app.obstacles(i,j) == 1)
                        continue
                    end
                    
                    % Position de la case considérée dans la matrice
                    % d'adjacence
                    pos_graph = j+10*(i-1);
                    
                    % On établit la matrice : 0 si obstacle, 1 si case
                    % libre
                    if (i<10)
                        app.near(pos_graph, pos_graph+10) = 1-min([app.obstacles(i+1,j) 2-app.obstacles(i+1,j)]);
                    end
                    if (i>1)
                        app.near(pos_graph, pos_graph-10) = 1-min([app.obstacles(i-1,j) 2-app.obstacles(i-1,j)]);
                    end
                    if (j<10)
                        app.near(pos_graph, pos_graph+1) = 1-min([app.obstacles(i,j+1) 2-app.obstacles(i,j+1)]);
                    end
                    if (j>1)
                        app.near(pos_graph, pos_graph-1) = 1-min([app.obstacles(i,j-1) 2-app.obstacles(i,j-1)]);
                    end
                
                end
            end
            
            % On transforme notre matrice en graphe
            G = graph(app.near);
            
            % On définit les positions initiales et finales entre
            % lesquelles calculer le chemin le plus court
            posIni = 10*(app.RobotXIni-1)+app.RobotYIni;
            posFin = 10*(app.CaseFinaleX-1)+app.CaseFinaleY;
            
            % On calcule le chemin le plus court
            app.cheminLePlusCourt = distances(G, posIni, posFin);

        end
        
        function ModeMaze(app)
        % Fonction servant à définir les matrices des différents niveaux
        % pour le mode de jeu "Maze", s'active après sélection du
        % mode de jeu et activation du bouton "Play"
            
            switch app.SelectLevelDropDown.Value
                
                case 'Novice'
                    
                    app.obstacles = [0 0 0 0 0 1 0 0 0 2; 0 0 0 1 0 0 0 1 1 1; 0 1 1 1 1 1 1 1 0 0; 0 1 0 0 0 0 0 1 0 1;...
                        0 1 0 1 1 1 0 0 0 0; 0 1 0 0 0 0 1 1 1 0; 0 1 1 1 1 0 0 0 1 0; 0 0 0 1 1 0 0 0 1 0;...
                        0 0 0 1 1 1 1 0 0 0; 0 0 0 0 0 0 0 0 1 0];
            
                    app.RobotXIni = 5;
                    app.RobotYIni = 9;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;
                    
                case 'Intermediate'
                    
                    app.obstacles = [0 0 0 0 0 0 0 0 0 0; 0 1 1 1 1 0 1 1 1 0; 0 1 0 0 0 1 0 0 1 0; 0 1 0 1 0 0 1 0 1 0;...
                        0 0 0 1 0 0 1 0 0 0; 0 1 0 0 0 0 1 0 0 1; 0 1 0 1 1 0 1 0 1 0; 1 0 0 0 0 1 0 0 0 0; 2 1 1 1 0 0 1 1 1 0; 0 0 0 0 0 1 0 0 0 0];
            
                    app.RobotXIni = 1;
                    app.RobotYIni = 10;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;
                    
                case 'Advanced'
                    
                    app.obstacles = [0 0 0 1 0 0 0 0 0 0; 0 1 0 0 0 1 1 1 0 1; 0 1 0 1 1 0 0 0 0 0; 1 0 0 1 0 0 1 1 1 0; ...
                        0 0 1 0 1 0 0 0 1 0; 1 0 1 0 2 1 1 0 0 1; 0 0 1 0 1 0 0 1 0 1; 0 1 0 0 0 0 1 0 0 0; ...
                        0 1 0 1 1 1 0 1 1 0; 0 0 0 1 0 0 0 0 0 0];
            
                    app.RobotXIni = 1;
                    app.RobotYIni = 10;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;
                                
                case 'Master'    
                    
                    app.obstacles = [0 0 0 1 0 0 0 0 0 0; 0 1 0 0 0 1 1 1 0 1; 0 1 0 1 1 0 0 0 0 0; 1 0 0 1 0 0 1 1 1 0; ...
                        0 0 1 0 1 0 0 0 1 0; 1 0 1 0 2 1 1 0 0 1; 0 0 1 0 1 0 0 1 0 1; 0 1 0 0 0 0 1 0 0 0; ...
                        0 1 0 1 1 1 0 1 1 0; 0 0 0 1 0 0 0 0 0 0];
            
                    app.RobotXIni = 1;
                    app.RobotYIni = 10;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;
                    app.CaseFinaleX = 6;
                    app.CaseFinaleY = 5;
                    
            end
            
        end
        
        function ModePathFinding(app)
        % Fonction servant à définir les matrices des différents niveaux
        % pour le mode de jeu "Shortest Path", s'active après sélection du
        % mode de jeu et activation du bouton "Play"
        
            switch app.SelectLevelDropDown.Value 
                
                case 'Novice'
                    
                    app.obstacles = [0 0 0 0 0 0 0 1 0 0; 0 1 1 1 0 1 0 1 0 0; 0 1 0 0 0 0 0 1 0 0; 0 1 0 1 0 1 1 1 0 0; 0 0 0 1 0 0 0 1 0 0; ...
                        0 1 0 1 0 1 0 1 0 0; 0 0 0 0 0 1 0 1 0 0; 1 1 1 1 1 1 0 1 0 0; 0 0 0 0 0 0 0 0 0 0; 0 0 0 0 0 0 0 0 0 2];
                    
                    app.RobotXIni = 1;
                    app.RobotYIni = 1;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;
                    app.CaseFinaleX = 10;
                    app.CaseFinaleY = 10;
                                                       
                case 'Intermediate'
                    
                    app.obstacles = [0 0 0 0 0 0 0 0 0 0; 0 1 1 1 1 0 1 1 1 0; 0 1 0 0 0 1 0 0 1 0; 0 1 0 1 0 0 1 0 1 0;...
                        0 0 0 1 0 0 1 0 0 0; 0 1 0 0 0 0 1 0 0 1; 0 1 0 1 1 0 1 0 1 0; 1 0 0 0 0 1 0 0 0 0; 2 1 1 1 0 0 1 1 1 0; 0 0 0 0 0 1 0 0 0 0];
            
                    app.RobotXIni = 1;
                    app.RobotYIni = 10;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;
                    app.CaseFinaleX = 9;
                    app.CaseFinaleY = 1;
                    
                case 'Advanced'
                    
                    app.obstacles = [0 0 0 1 0 0 0 0 0 0; 0 1 0 0 0 1 1 1 0 1; 0 1 0 1 1 0 0 0 0 0; 1 0 0 1 0 0 1 1 1 0; ...
                        0 0 1 0 1 0 0 0 1 0; 1 0 1 0 2 1 1 0 0 1; 0 0 1 0 1 0 0 1 0 1; 0 1 0 0 0 0 1 0 0 0; ...
                        0 1 0 1 1 1 0 1 1 0; 0 0 0 1 0 0 0 0 0 0];
            
                    app.RobotXIni = 1;
                    app.RobotYIni = 10;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;
                    app.CaseFinaleX = 6;
                    app.CaseFinaleY = 5;
                    
                case 'Master'
                    
                    app.obstacles = [0 0 0 0 0 0 0 0 0 0; 0 1 1 1 1 0 1 1 1 0; 0 1 0 0 0 1 0 0 1 0; 0 1 0 1 0 0 1 0 1 0;...
                        0 0 0 1 0 0 1 0 0 0; 0 1 0 0 0 0 1 0 0 1; 0 1 0 1 1 0 1 0 1 0;...
                        1 0 0 0 0 1 0 0 0 0; 2 1 1 1 0 0 1 1 1 0; 0 0 0 0 0 1 0 0 0 0];
            
                    app.RobotXIni = 1;
                    app.RobotYIni = 10;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;
                    app.CaseFinaleX = 9;
                    app.CaseFinaleY = 1;
                    
            end
            
        end

        function DisplayPathDone(app)
        % Fonction servant à afficher un message de réussite ou échec pour
        % le mode de jeu "Shortest Path" : une lampe s'allume en rouge ou
        % vert, et une image ainsi que deux messages texte s'affichent
        
            if length(app.instructions) <= app.cheminLePlusCourt
                
                % On choisit l'image à afficher
                app.Image.ImageSource = 'Connie.jpg';
                app.Image.Visible = 'on';
                % On allume la lampe
                app.Lamp.Color = 'green';
                
                % Affichage du premier message
                app.EditField_2.Value = 'SUCCESS!!';
                        
                % On affiche dans le 2e message la longueur du chemin pris
                % par l'utilisateur, il faut convertir ce nombre en une
                % chaine de caractères
                app.lengthPath = int2str(length(app.instructions));
                app.Message = ['You did it in ' app.lengthPath '! It is the shortest path, congratulations!'];
                app.InstructionsEditField.Value = app.Message;
                    
            else
                        
                app.Image.ImageSource = 'Sasha.jpg';
                app.Image.Visible = 'on';
                app.Lamp.Color = 'red';
                app.EditField_2.Value = 'TRY AGAIN...';
                 
                app.lengthPath = int2str(length(app.instructions));
                app.Message = ['You did it in ' app.lengthPath '. You can do it in less...'];
                app.InstructionsEditField.Value = app.Message;
                
            end
            
        end
        
        function InitialisationRobot(app)
        % Fonction servant, après chaque appui du bouton "Play", à envoyer 
        % la position initiale du robot au programme embarqué
            
            % le programme embarqué ne reçoit que des instructions sous
            % forme de caractère unique, il faut donc convertir les entiers
            % RobotXIni et RobotYIni en chaine de caractères
            app.commandeX = int2str(app.RobotXIni);
            app.commandeY = int2str(app.RobotYIni);
                     
            % on envoie le caractère "i" à la Nucléo pour lui indiquer le début
            % de l'initialisation
            writeline(app.s,'i');
            
            % On attend que la Nucléo renvoie le caractère "n" indiquant
            % qu'elle est prête à recevoir l'instruction suivante sans
            % risque d'erreur
            while(1)
                app.SignalNucleo = read(app.s,1,"char");
                if app.SignalNucleo == 'n'
                    break
                end
             end
             
             % On envoie le dernier caractère de commande X pour indiquer
             % la position X du robot (pour RobotXIni = 10, renvoie "0"
             % uniquement)
             writeline(app.s, app.commandeX(length(app.commandeX)));
             while(1)
                app.SignalNucleo = read(app.s,1,"char");
                if app.SignalNucleo == 'n'
                    break
                end
             end
             
             writeline(app.s, app.commandeY(length(app.commandeY)));
             while(1)
                app.SignalNucleo = read(app.s,1,"char");
                if app.SignalNucleo == 'n'
                    break
                end
             end
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialisation(app)
        % Fonction qui affiche l'interface dans son état initial après
        % lancement de l'application
            
            % On définit le port à utiliser pour la liaison série, ici 'COM1',
            % avec un nombre de baud de 115200 s-1
            % app.s = serialport("COM1", 115200);
            
            % On fixe la base (X,Y) comme étant orthonormée
            set(app.UIAxes, 'DataAspectRatio', [1 1 1])
            
            % On définit les limites des axes X et Y au nombre de cases +1
            % pour l'affichage de l'échelle des axes
            app.UIAxes.XLim = [0 app.CaseSize*(app.CaseNombre+1)+1];
            app.UIAxes.YLim = [0 app.CaseSize*(app.CaseNombre+1)+1];
            
            % On n'affiche pas les axes X et Y
            set(app.UIAxes, 'Visible','off')
            
            % On affiche l'échelle des axes, soit le numéro des cases
            for i = 1:app.CaseNombre
                text(app.UIAxes, i*app.CaseSize+5, app.CaseSize/2, int2str(i))   
                text(app.UIAxes, app.CaseSize/2, i*app.CaseSize+5, int2str(i))                
            end
            
            % Matrice affichée lors de l'initialisation, dessin abstrait
            app.obstacles = [0 0 0 0 0 0 0 0 0 0; 0 1 0 1 0 0 0 0 1 0; 0 0 1 0 0 0 0 1 0 0; 0 1 0 1 0 0 1 0 0 0;...
                0 0 0 0 1 1 0 0 0 0; 0 0 0 0 1 1 0 0 0 0; 0 1 0 1 0 0 1 0 0 0; 0 0 1 0 0 0 0 1 0 0;...
                0 1 0 1 0 0 0 0 1 0; 0 0 0 0 0 0 0 0 0 0];
            % On affiche la grille
            afficherGrille(app);
            
            % On définit la couleur de la lampe en blanc
            app.Lamp.Color = [1 1 1];
            
            % On crée une image (invisible pour le moment) à une position
            % précise sur l'interface ; image servira par la suite comme
            % message de réussite/échec pour certains modes de jeu
            app.Image = uiimage(app.Panel_5,'Visible','off');
            app.Image.Position = [11 11 103 115];
            
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
        % Fonction servant à faire avancer le robot d'une case vers le
        % haut en l'absence d'obstacle après activation du bouton 'UP'
            
            % Si l'utilisateur est arrivé sur la case finale en mode "Maze" ou
            % "Shortest Path", ou si l'utilisateur a cliqué sur le bouton
            % "Done" avant d'avoir fini le niveau, le robot est bloqué et ne
            % peut plus bouger
            if not(app.checkMazeDone) && not(app.checkPathDone) && not(app.checkPathTooSoon)
            
                % On teste la position du robot pour ne pas sortir de la grille
                if app.RobotY < app.CaseNombre && app.obstacles(app.RobotX,app.RobotY+1) ~= 1
                    
                    % On déplace le robot d'une case vers le haut
                    app.deplacerRobot(0,1)
                    
                    % On enregistre la commande 'aller vers le haut' dans la ligne
                    % d'instructions à envoyer au programme embarqué par la
                    % liaison RS232
                    app.instructions = [app.instructions '↑'];
                    
                    % On affiche l'ensemble des instructions commandées par
                    % l'utilisateur dans l'éditeur de texte
                    app.InstructionsEditField.Value = app.instructions;
                    
                end
                
                % On affiche la position (X,Y) du robot sur l'interface
                app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX));
                app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY));
                
                % Si le robot vient de se déplacer sur la case d'arrivée en
                % mode "Shortest Path", on affiche le message de
                % réussite/échec
                if app.checkPathDone
                    
                    DisplayPathDone(app);
                
                end
                
            end
            
        end

        % Button pushed function: RIGHTButton
        function RIGHTButtonPushed(app, event)
        % Fonction servant à faire avancer le robot d'une case vers la
        % droite en l'absence d'obstacle après activation du bouton 'RIGHT'
        % pour des commentaires plus détaillés, se reporter à la fonction
        % UPButtonPushed()
        
            if not(app.checkMazeDone) && not(app.checkPathDone) && not(app.checkPathTooSoon)
            
                if app.RobotX < app.CaseNombre && app.obstacles(app.RobotX+1,app.RobotY) ~= 1
                    app.deplacerRobot(1,0)
                    app.instructions = [app.instructions '→'];
                    app.InstructionsEditField.Value = app.instructions;
                end
                
                app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX));
                app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY));
                
                if app.checkPathDone
                    
                    DisplayPathDone(app);
                    
                end
                
            end
        
        end

        % Button pushed function: LEFTButton
        function LEFTButtonPushed(app, event)
        % Fonction servant à faire avancer le robot d'une case vers la
        % gauche en l'absence d'obstacle après activation du bouton 'LEFT'
        % pour des commentaires plus détaillés, se reporter à la fonction
        % UPButtonPushed()
        
            if not(app.checkMazeDone) && not(app.checkPathDone) && not(app.checkPathTooSoon)
            
                if app.RobotX > 1 && app.obstacles(app.RobotX-1,app.RobotY) ~= 1
                    app.deplacerRobot(-1,0) 
                    app.instructions = [app.instructions '←'];
                    app.InstructionsEditField.Value = app.instructions;
                end
                
                app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX));
                app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY));  
                
                if app.checkPathDone
                    
                    DisplayPathDone(app);
                    
                end
                
            end
        
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
        % Fonction servant à faire avancer le robot d'une case vers le
        % bas en l'absence d'obstacle après activation du bouton 'DOWN'
        % pour des commentaires plus détaillés, se reporter à la fonction
        % UPButtonPushed()
            
            if not(app.checkMazeDone) && not(app.checkPathDone) && not(app.checkPathTooSoon)
            
                if app.RobotY > 1 && app.obstacles(app.RobotX,app.RobotY-1) ~= 1
                    app.deplacerRobot(0,-1)
                    app.instructions = [app.instructions '↓'];
                    app.InstructionsEditField.Value = app.instructions;
                end
                
                app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX));
                app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY)); 
                
                if app.checkPathDone
                    
                    DisplayPathDone(app);
                    
                end
                
            end
        
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        % Fonction servant à afficher l'interface dans son état initial
        % (robot en position initiale, instructions et grille vides, image 'off'
        % lampe blanche, booléens à 0) après activation du bouton 'RESET'
        % Pour plus de commentaires, se reporter à la fonction
        % "initialisation()"
            
            
            % On envoie la commande 'reset' au programme embarqué par la
            % liaison RS232, afin que le robot physique se replace
            % également en position initiale
            % writeline(app.s, 'z');
                        
            afficherGrille(app)
            
            app.RobotX = app.RobotXIni;
            app.RobotY = app.RobotYIni;
            rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX, app.CaseSize*app.RobotY, app.CaseSize, app.CaseSize],...
                'FaceColor',app.RobotColor,'Curvature',[1 1])       
            
            app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX));
            app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY));
            
            % On réinitialise la ligne de commande à une liste vide
            app.instructions = [];
            
            % On vide les instructions précédentes de l'éditeur de texte
            app.InstructionsEditField.Value = ' ';
            app.EditField_2.Value = ' ';
            
            app.Lamp.Color = [1 1 1];
            app.Image.Visible = 'off';
            
            % On réinitialise les booléens correspondants à un niveau fini
            % en mode de jeu "Maze" ou "Shortest Path", ou après appui sur
            % le bouton "Done" en "Shortest Path"
            app.checkMazeDone = 0;
            app.checkPathDone = 0;
            app.checkPathTooSoon = 0;
            
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
        % Fonction servant à envoyer une liste d'instructions au programme
        % embarqué par une liaison RS232 après activation du bouton 'DONE'
        
            % Le programme embarqué lit les caractères envoyés un à un
            for caractere = 1:length(app.instructions)
           
                % Le programme embarqué ne lit pas les caractères '↑', ...
                % on convertit chaque flèche en un caractère lisible
                % par le programme embarqué
                switch app.instructions(caractere)
                
                    case '↑'
                        writeline(app.s,'u');
                    case '→'
                        writeline(app.s,'r');
                    case '←'
                        writeline(app.s,'l');
                    case '↓'
                        writeline(app.s,'d');
                end
                
                % On attend que la Nucléo renvoie le caractère "n" indiquant
                % qu'elle est prête à recevoir l'instruction suivante sans
                % risque d'erreur
                while(1)
                    app.SignalNucleo = read(app.s,1,"char");
                    if app.SignalNucleo == 'n'
                        break
                    end
                end
            
            end
            
            % En modes "Sandbox" ou "Maze", on vide simplement les
            % instructions qui viennent d'être envoyées avant de rentrer de
            % nouvelles commandes pour éviter les doublons
            if app.checkSandbox || app.checkMaze
                
                app.instructions = [];
                app.InstructionsEditField.Value = ' ';                            
            
            % En mode "Shortest Path", si on appuie trop tôt sur le bouton
            % "Done", il n'est plus possible de bouger le robot, il faut
            % absolument appuyer sur "Reset"
            elseif app.checkPath && not(app.checkPathDone)
                
                app.checkPathTooSoon = 1;
            
            end

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
        % Fonction servant à annuler la commande de direction du robot
        % qui vient d'être effectuée 
        
            % Le bouton "Delete" n'est utilisable que lorsque le robot est
            % en cours de parcours, qu'une commande a déjà été effectuée et
            % que le bouton "Done" n'a pas été activé en mode "Shortest
            % Path"
            if not(isempty(app.instructions)) && not(app.checkMazeDone) && not(app.checkPathDone)...
                    && not(app.checkPathTooSoon)
                
                % Annuler la commande précédente revient à déplacer le robot en
                % sens inverse
                % la commande est donc différente selon chaque cas
                % les commentaires détaillés sont donnés pour le cas '↑', il
                % suffit d'adapter les autres cas
                switch app.instructions(length(app.instructions))
                                        
                    case '↑'
                        % On déplace le robot d'une case vers le bas
                        app.deplacerRobot(0,-1)
                        
                        % On vide la case où se trouvait le robot précédemment en
                        % la rendant intégralement blanche
                        rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX, app.CaseSize*(app.RobotY+1), app.CaseSize, app.CaseSize],...
                            'FaceColor',app.CaseColor)
                                                
                    case '→'
                        app.deplacerRobot(-1,0)
                        rectangle(app.UIAxes, 'Position', [app.CaseSize*(app.RobotX+1), app.CaseSize*app.RobotY, app.CaseSize, app.CaseSize],...
                            'FaceColor',app.CaseColor)
                    case '←'
                        app.deplacerRobot(1,0)
                        rectangle(app.UIAxes, 'Position', [app.CaseSize*(app.RobotX-1), app.CaseSize*app.RobotY, app.CaseSize, app.CaseSize],...
                            'FaceColor',app.CaseColor)
                    case '↓'
                        app.deplacerRobot(0,1)
                        rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX, app.CaseSize*(app.RobotY-1), app.CaseSize, app.CaseSize],...
                            'FaceColor',app.CaseColor)
                end
                
                % On retire la dernière instruction de la ligne de commande
                app.instructions(length(app.instructions)) = [];
                
                % On efface la dernière instruction de l'éditeur de texte
                app.InstructionsEditField.Value = app.instructions;
                            
            end
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
        % Fonction servant à mettre en place les différentes
        % fonctionnalités pour un mode de jeu et un niveau sélectionnées,
        % puis à (re)lancer le jeu
            
            % On initialise tous les booléens à 0
            app.checkSandbox = 0;       % choix du mode "Sandbox"
            app.checkMaze = 0;          % choix du mode "Maze"
            app.checkMazeDone = 0;      % la grille "Maze" choisie a été effectuée
            app.checkPath = 0;          % choix du mode "Shortest Path"
            app.checkPathDone = 0;      % la grille "Shortest Path" choisie a été effectuée
            app.checkPathTooSoon = 0;   % le bouton "Done" a été activé avant d'avoir fini le niveau
            
            % On vide les éditeurs de texte et la ligne de commandes
            app.InstructionsEditField.Value = ' ';
            app.EditField_2.Value = ' ';
            app.instructions = [];
            
            % On rend l'image pouvant avoir été précédemment affichée
            % invisible et on rend la lampe blanche
            app.Image.Visible = 'off';
            app.Lamp.Color = [1 1 1]; 
            
            % Les fonctionnalités sont différentes en fonction du mode de
            % jeu
            switch app.SelectModeDropDown.Value
                
                case 'Sandbox'
                    
                    app.checkSandbox = 1;
                    
                    % En mode "Sandbox", on n'affiche pas d'obstacles, c'est un mode découverte 
                    app.obstacles = zeros(10);
                    app.RobotXIni = 1;
                    app.RobotYIni = 1;
                    app.RobotX = app.RobotXIni;
                    app.RobotY = app.RobotYIni;                    
                    
                case 'Maze'
                    
                    app.checkMaze = 1;
                    ModeMaze(app);
                                        
                case 'Shortest Path'
                    
                    app.checkPath = 1;
                    ModePathFinding(app);
                                        
            end
            
            % Les fonctions ModeMaze() et ModePathFinding() définissent la
            % matrice à utiliser, qu'on affiche ici, ainsi que la position
            % initiale du robot, qu'on affiche également
            afficherGrille(app);
            rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotXIni, app.CaseSize*app.RobotYIni, app.CaseSize, app.CaseSize],...
                'FaceColor',app.RobotColor,'Curvature',[1 1])
            
            app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX));
            app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY));
            
            % On envoie les instructions d'initialisation au programme
            % embarqué
            % InitialisationRobot(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.UPButton = uibutton(app.GridLayout5, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.LEFTButton = uibutton(app.GridLayout5, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTButtonPushed, true);
            app.LEFTButton.Text = 'LEFT';
            app.DOWNButton = uibutton(app.GridLayout5, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Text = 'DOWN';
            app.RIGHTButton = uibutton(app.GridLayout5, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTButtonPushed, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.ImageSource = 'wallE.jpg';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.RobotXLabel = uilabel(app.GridLayout6);
            app.RobotXLabel.Text = 'RobotX';
            app.RobotYLabel = uilabel(app.GridLayout6);
            app.RobotYLabel.Text = 'RobotY';
            app.ResetButton = uibutton(app.GridLayout6, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Panel_2 = uipanel(app.GridLayout4);
            app.GridLayout9 = uigridlayout(app.Panel_2);
            app.DeleteButton = uibutton(app.GridLayout9, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.DoneButton = uibutton(app.GridLayout9, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done !';
            app.Panel = uipanel(app.GridLayout);
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Value = 'Welcome to BotSensei !';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'hange.jpg';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.Panel_3);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel_4 = uipanel(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.Panel_4);
            app.SelectLevelDropDownLabel = uilabel(app.GridLayout8);
            app.SelectLevelDropDownLabel.Text = 'Select Level';
            app.SelectLevelDropDown = uidropdown(app.GridLayout8);
            app.SelectLevelDropDown.Items = {'Novice', 'Intermediate', 'Advanced', 'Master'};
            app.SelectLevelDropDown.Value = 'Novice';
            app.PlayButton = uibutton(app.GridLayout8, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Panel_5 = uipanel(app.GridLayout8);
            app.SelectModeDropDownLabel = uilabel(app.GridLayout8);
            app.SelectModeDropDownLabel.Text = 'Select Mode';
            app.SelectModeDropDown = uidropdown(app.GridLayout8);
            app.SelectModeDropDown.Items = {'Sandbox', 'Maze', 'Shortest Path'};
            app.SelectModeDropDown.Value = 'Sandbox';
            app.EditField_2 = uieditfield(app.GridLayout8, 'text');
            app.GridLayout10 = uigridlayout(app.GridLayout7);
            app.GridLayout11 = uigridlayout(app.GridLayout7);
            app.Lamp = uilamp(app.GridLayout11);
            app.InstructionsEditFieldLabel = uilabel(app.GridLayout7);
            app.InstructionsEditFieldLabel.Text = 'Instructions';
            app.InstructionsEditField = uieditfield(app.GridLayout7, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BotSensei

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialisation)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end