classdef rectangle_sur_figure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        UIAxes            matlab.ui.control.UIAxes
        GridLayout2       matlab.ui.container.GridLayout
        GridLayout3       matlab.ui.container.GridLayout
        GridLayout4       matlab.ui.container.GridLayout
        GridLayout5       matlab.ui.container.GridLayout
        UPButton          matlab.ui.control.Button
        LEFTButton        matlab.ui.control.Button
        DOWNButton        matlab.ui.control.Button
        RIGHTButton       matlab.ui.control.Button
        GridLayout6       matlab.ui.container.GridLayout
        RobotXLabel       matlab.ui.control.Label
        RobotYLabel       matlab.ui.control.Label
        ResetButton       matlab.ui.control.Button
        ParcoursCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CaseColor = [1 1 1]
        CaseColorOld = [0 0 1]
        CaseSize = 10 % Description
        CaseNombre = 10 % Description
        RobotColor = [1 0.7 0.1]
        RobotX = 1
        RobotY = 1
    end
    
    methods (Access = private)
        
        function deplacerRobot(app, xx, yy)
            % Suppression robot en case précédente
            rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX, app.CaseSize*app.RobotY, app.CaseSize, app.CaseSize], 'FaceColor',app.CaseColor)
            if app.ParcoursCheckBox.Value == 1
                rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX+app.CaseSize/3, app.CaseSize*app.RobotY+app.CaseSize/3, ...
                                                    app.CaseSize/3, app.CaseSize/3], ...
                                                    'FaceColor',app.CaseColorOld)
            end
            % Changement de position du robot
            app.RobotX = app.RobotX + xx
            app.RobotY = app.RobotY + yy
            % Ajout d'une case pour la nouvelle position
            rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX, app.CaseSize*app.RobotY, app.CaseSize, app.CaseSize], 'FaceColor',app.RobotColor,'Curvature',[1 1])                   
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialisation(app)
            set(app.UIAxes, 'DataAspectRatio', [1 1 1])
            app.UIAxes.XLim = [0 app.CaseSize*(app.CaseNombre+1)+1]
            app.UIAxes.YLim = [0 app.CaseSize*(app.CaseNombre+1)+1]
            set(app.UIAxes, 'Visible','off') 
            for i = 1:app.CaseNombre
                text(app.UIAxes, i*app.CaseSize+5, app.CaseSize/2, int2str(i))   
                text(app.UIAxes, app.CaseSize/2, i*app.CaseSize+5, int2str(i))                
            end
            for i = 1:app.CaseNombre
                for j = 1:app.CaseNombre
                    rectangle(app.UIAxes, 'Position', [app.CaseSize*i, app.CaseSize*j, app.CaseSize, app.CaseSize], 'FaceColor', app.CaseColor)
                end
            end
            rectangle(app.UIAxes, 'Position', [app.CaseSize*app.RobotX, app.CaseSize*app.RobotY, app.CaseSize, app.CaseSize], 'FaceColor',app.RobotColor,'Curvature',[1 1])
            app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX))
            app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY))
            app.ParcoursCheckBox.Value = 1
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            if app.RobotY < app.CaseNombre
                app.deplacerRobot(0,1)
            end
            app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX))
            app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY))
        end

        % Button pushed function: RIGHTButton
        function RIGHTButtonPushed(app, event)
            if app.RobotX < app.CaseNombre
                app.deplacerRobot(1,0)
            end
            app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX))
            app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY))            
        end

        % Button pushed function: LEFTButton
        function LEFTButtonPushed(app, event)
            if app.RobotX > 1
                app.deplacerRobot(-1,0)
            end
            app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX))
            app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY))                
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            if app.RobotY > 1
                app.deplacerRobot(0,-1)
            end
            app.RobotXLabel.Text = strcat('X=',int2str(app.RobotX))
            app.RobotYLabel.Text = strcat('Y=',int2str(app.RobotY))            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.RobotX = 1
            app.RobotY = 1
            app.initialisation()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.UPButton = uibutton(app.GridLayout5, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.LEFTButton = uibutton(app.GridLayout5, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTButtonPushed, true);
            app.LEFTButton.Text = 'LEFT';
            app.DOWNButton = uibutton(app.GridLayout5, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Text = 'DOWN';
            app.RIGHTButton = uibutton(app.GridLayout5, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTButtonPushed, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.RobotXLabel = uilabel(app.GridLayout6);
            app.RobotXLabel.Text = 'RobotX';
            app.RobotYLabel = uilabel(app.GridLayout6);
            app.RobotYLabel.Text = 'RobotY';
            app.ResetButton = uibutton(app.GridLayout6, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ParcoursCheckBox = uicheckbox(app.GridLayout6);
            app.ParcoursCheckBox.Text = 'Parcours';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rectangle_sur_figure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialisation)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end